classdef CLSMspectraLabGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CLSMspectraLab                  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        XANESWizardMenu                 matlab.ui.container.Menu
        DebugMenu                       matlab.ui.container.Menu
        BackgroundMenu                  matlab.ui.container.Menu
        LinearMenu                      matlab.ui.container.Menu
        ComputebackgroundMenu           matlab.ui.container.Menu
        PlotacoefficientMenu            matlab.ui.container.Menu
        PlotbcoefficientMenu            matlab.ui.container.Menu
        ReferencespectrumMenu           matlab.ui.container.Menu
        ComputebackgroundMenu_2         matlab.ui.container.Menu
        PlotbackgroundMenu              matlab.ui.container.Menu
        MaskMenu                        matlab.ui.container.Menu
        FirstPCAMenu                    matlab.ui.container.Menu
        ComputemaskMenu                 matlab.ui.container.Menu
        ComputeforcurrentdataMenu       matlab.ui.container.Menu
        Plothistogram1steigenimageMenu  matlab.ui.container.Menu
        Plot1steigenimageMenu           matlab.ui.container.Menu
        PlotthresholdperzsliceMenu      matlab.ui.container.Menu
        EmptyMenu                       matlab.ui.container.Menu
        ComputemaskMenu_2               matlab.ui.container.Menu
        AddsatpixelsMenu                matlab.ui.container.Menu
        PrintpixelsinmaskMenu           matlab.ui.container.Menu
        ResetmaskMenu                   matlab.ui.container.Menu
        NormalizationMenu               matlab.ui.container.Menu
        MaxMenu                         matlab.ui.container.Menu
        ComputenormMenu_3               matlab.ui.container.Menu
        SummedMenu                      matlab.ui.container.Menu
        ComputenormMenu                 matlab.ui.container.Menu
        SinglepointMenu                 matlab.ui.container.Menu
        ComputenormMenu_2               matlab.ui.container.Menu
        ClusteringMenu                  matlab.ui.container.Menu
        ImportXANESWizardMenu           matlab.ui.container.Menu
        SetfilenameforcurrentMenu       matlab.ui.container.Menu
        ComputeMenu                     matlab.ui.container.Menu
        SimilarityfitMenu               matlab.ui.container.Menu
        LoadreferencespectraMenu        matlab.ui.container.Menu
        ComputeMenu_2                   matlab.ui.container.Menu
        PlotclustermapMenu_2            matlab.ui.container.Menu
        PlotspectrumclustersMenu_2      matlab.ui.container.Menu
        PlotspectrumclustersallMenu     matlab.ui.container.Menu
        CopyspectrumclusterstoclipboardMenu  matlab.ui.container.Menu
        CopyspectrumclusterstoclipboardallMenu  matlab.ui.container.Menu
        ClustermapprocesssettingsMenu   matlab.ui.container.Menu
        PlotprocessedclustersmapMenu    matlab.ui.container.Menu
        SaveclusterstatisticsMenu       matlab.ui.container.Menu
        SaveclusterstatisticsallMenu    matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        BininputimageMenu               matlab.ui.container.Menu
        SaveXANESWizardspectrumMenu     matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        PlotspectrumMenu                matlab.ui.container.Menu
        PlotROIsindivuallyMenu          matlab.ui.container.Menu
        AllMenu                         matlab.ui.container.Menu
        ROIinzsliceMenu                 matlab.ui.container.Menu
        ROIMenu                         matlab.ui.container.Menu
        SinglepixelsMenu                matlab.ui.container.Menu
        AlltoclipboardMenu              matlab.ui.container.Menu
        ROItoclipboardMenu              matlab.ui.container.Menu
        ForegroundbackgroundMenu        matlab.ui.container.Menu
        XANESWizardClustersMenu         matlab.ui.container.Menu
        PlotchannelsMenu                matlab.ui.container.Menu
        RGBmapsliceMenu                 matlab.ui.container.Menu
        RatiomapsliceMenu               matlab.ui.container.Menu
        RatiomapslicenormalizedMenu     matlab.ui.container.Menu
        Plotcurrentmask2D3DMenu         matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ApplynormalizationCheckBox      matlab.ui.control.CheckBox
        endnmEditField                  matlab.ui.control.NumericEditField
        endnmEditFieldLabel             matlab.ui.control.Label
        startnmEditField                matlab.ui.control.NumericEditField
        startnmEditFieldLabel           matlab.ui.control.Label
        ApplybackgroundCheckBox         matlab.ui.control.CheckBox
        Channel3EditField               matlab.ui.control.EditField
        Channel3EditFieldLabel          matlab.ui.control.Label
        InvertCheckBox                  matlab.ui.control.CheckBox
        Channel2EditField               matlab.ui.control.EditField
        Channel2EditFieldLabel          matlab.ui.control.Label
        Channel1EditField               matlab.ui.control.EditField
        Channel1EditFieldLabel          matlab.ui.control.Label
        ApplymaskCheckBox               matlab.ui.control.CheckBox
        DeleteselectedROIButton         matlab.ui.control.Button
        ListBox                         matlab.ui.control.ListBox
        DrawROIButton                   matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        tSpinner                        matlab.ui.control.Spinner
        tSpinnerLabel                   matlab.ui.control.Label
        zSpinner                        matlab.ui.control.Spinner
        zSpinnerLabel                   matlab.ui.control.Label
        VisualizationModeDropDown       matlab.ui.control.DropDown
        VisualizationModeDropDownLabel  matlab.ui.control.Label
        dataSpinner                     matlab.ui.control.Spinner
        dataSpinnerLabel                matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        clObj; % holds CLSMspectraLab object
        isVolume; % if true, clObj constains one XYZC, else multiple XYC
        ROItable; % table with ROIs
        activePolygons % array with polygon ROIs in this frame
        polygonId = 1; % index of the last polygon drawn
        previousSlice = 1; % deprecated?
        rgb_bins = []; % RGB values of bins
    end
    
    methods (Access = private)

        function errorBox(app, err)
            % if there is an error invoked, we output it in a dialog box
            if app.DebugMenu.Checked
                errordlg(getReport(err,'extended','hyperlinks','off'),'Error');
            else
                errordlg(getReport(err,'basic','hyperlinks','off'),'Error');
            end
        end
        
        function updateZTSliders(app)
                ii = app.dataSpinner.Value; % number data set
                nZ = app.clObj.getDim(app.clObj.input(ii),'z');
                if nZ == 1
                    app.zSpinner.Value = 1;
                    app.zSpinner.Enable = false;
                else % set spinner limits
                    app.zSpinner.Value = 1;
                    app.zSpinner.Enable = true;
                    app.zSpinner.Limits = [1 nZ];
                end
                nT = app.clObj.getDim(app.clObj.input(ii),'t');
                if nT == 1
                    app.tSpinner.Value = 1;
                    app.tSpinner.Enable = false;
                else % set spinner limits
                    app.tSpinner.Value = 1;
                    app.tSpinner.Enable = true;
                    app.tSpinner.Limits = [1 nT];
                end
        end
        
        function [ID,varargout] = getCurrentZT(app)
            
            ID = app.dataSpinner.Value;
            varargout{1} = app.zSpinner.Value;
            varargout{2} = app.tSpinner.Value;
            
        end
        
        function updateROIListBox(app)
            if isempty(app.activePolygons)
                app.ListBox.Items = {''};
                app.ListBox.ItemsData = cell(0);
                app.ListBox.Value = cell(0);
            else
                labels = [app.activePolygons.UserData];
                items = cell(numel(labels),1);
                for ii = 1:numel(labels)
                    items{ii} = sprintf('Region %d',labels(ii));
                end
                app.ListBox.Items = items;
                app.ListBox.ItemsData = labels;
                app.ListBox.Value = labels(1);
            end

        end
        

        function polygonChanged(app,src,eventData)
             app.ROItable{src.UserData,5} = {src.Position};
        end
        
        function polygonClicked(app,src,eventData)
             app.ListBox.Value = src.UserData;
        end
        
        function setPolygonProps(app,h,label)
            
                % set listener
                addlistener(h,'ROIMoved',@app.polygonChanged);
                addlistener(h,'VertexAdded',@app.polygonChanged);
                addlistener(h,'VertexDeleted',@app.polygonChanged);
                addlistener(h,'ROIClicked',@app.polygonClicked);
                
                % set labels
                h.Label = num2str(label);
                h.LabelVisible = 'on';
                h.UserData = label;
                
                % add to active polygons
                app.activePolygons = [app.activePolygons h];
        end
        
        function updateUIAxes1(app,varargin)
            
            hbox = msgbox('Loading image... Please wait');

            % accept first varargin as Zidx. Otherwise take value from
            % spinner.
            if nargin > 1
                app.dataSpinner.Value = varargin{1};
            end
            
            [id,Zidx,Tidx] = getCurrentZT(app);
            
            % plot slice
            switch app.VisualizationModeDropDown.Value
                case 'Summed intensity'
                    app.clObj.plotSumIntensity2D(app.UIAxes,id,Zidx,Tidx);
                case 'RGB blocks' 
                    nChannels = app.clObj.input(id).getDim('c');
                    ch1 = 1:round(nChannels/3);
                    ch2 = (round(nChannels/3)+1):round(nChannels*2/3);
                    ch3 = (round(nChannels*2/3)+1):round(nChannels*3/3);
                    app.clObj.plotRGBcolormap2D(app.UIAxes,id,Zidx,Tidx,ch1,ch2,ch3)
                case 'True colour'
                    update_rgb_bins(app,false)
                    app.clObj.plotTrueColor2D(app.UIAxes,id,Zidx,Tidx,app.rgb_bins)
            end
            
            % delete active polygons from previous frame
            app.activePolygons = [];
            
            % draw ROI this slice
            ROIdata = app.ROItable.data;
            ROIz = app.ROItable.z;
            ROIt = app.ROItable.t;
            thisROIslices = find(ROIdata == id & ROIz == Zidx & ROIt == Tidx);
            if ~isempty(thisROIslices)
                for ii = 1:numel(thisROIslices)
                    h = images.roi.Polygon(app.UIAxes,'Position',app.ROItable.coords{thisROIslices(ii)},'Label',num2str(app.ROItable.label(thisROIslices(ii)))); % draw new from coords
                    app.setPolygonProps(h,app.ROItable.label(thisROIslices(ii))); % set properties and listeners
                end
            end
            updateROIListBox(app)
            
            xDim = app.UIAxes.Children.XData;
            yDim = app.UIAxes.Children.YData;
            xlim(xDim)
            ylim(yDim)
            
            % plot filename (stored in tag) and dimensions in X and Y
            app.Label.Text = {sprintf('%s',app.clObj.input(id).tag),sprintf('%i x %i px',xDim(2),yDim(2))};
            
            % close wait box
            close(hbox)
        end
        
        function update_rgb_bins(app,forceFlag)
            if isempty(app.clObj)
                nBins = 32;
            else
                [id,~,~] = getCurrentZT(app);
                nBins = app.clObj.input(id).getDim('c');
            end
            if ~forceFlag && ~isempty(app.rgb_bins)
                if nBins == size(app.rgb_bins,1)
                    return % when the number of bins hasnt changed
                end
            end
            
            app.rgb_bins = getRGBbins(app.startnmEditField.Value,app.endnmEditField.Value,nBins); % use default 32
            imshow(permute(repmat(app.rgb_bins,[1 1 10]),[3 1 2]),'Parent',app.UIAxes2)
            axis(app.UIAxes2,'off')
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            javaaddpath_for_CLSMspectraLab()
            update_rgb_bins(app,true)
        end

        % Callback function
        function LoadButtonPushed(app, event)
            LoadMenuSelected(app, event)
        end

        % Button pushed function: DrawROIButton
        function DrawROIButtonPushed(app, event)
            try
                % allow drawing new polygon
                h = drawpolygon(app.UIAxes,'FaceAlpha',0);
                
                % save to ROItable
                label = app.polygonId;
                T = table(app.dataSpinner.Value,app.zSpinner.Value,app.tSpinner.Value,label,{h.Position},'VariableNames',{'data','z','t','label','coords'});
                app.ROItable = [app.ROItable; T];
                
                % set properties and listeners polygon object
                app.setPolygonProps(h,label);

                % update id
                app.polygonId = app.polygonId + 1;
                
                % update list box
                updateROIListBox(app)
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: dataSpinner
        function dataSpinnerValueChanged(app, event)
            try
                % plot
                updateUIAxes1(app)
            catch err
                app.errorBox(err)
            end
        end

        % Callback function
        function PlotchannelsButtonPushed(app, event)
            try
                figure
                app.clObj.plotChannels;
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: DeleteselectedROIButton
        function DeleteselectedROIButtonPushed(app, event)
            try
                id = app.ListBox.Value;
                if ~isempty(id)
                    % remove handle
                    clearidx = [app.activePolygons.UserData] == id;
                    delete(app.activePolygons(clearidx))
                    app.activePolygons(clearidx) = [];
                    % remove table row
                    clearidx = app.ROItable.label == id;
                    app.ROItable(clearidx,:) = [];
                    updateROIListBox(app)
                end
            catch err
                app.errorBox(err)
            end
        end

        % Callback function
        function PlotchannelsROIssliceButtonPushed(app, event)
            try
                if isempty(app.ROItable) % check if is not empty
                    return
                end
                % prune ROItable
                ROItableSlice = app.ROItable(app.ROItable.data == app.dataSpinner.Value & ...
                    app.ROItable.z == app.zSpinner.Value & ...
                    app.ROItable.t == app.tSpinner.Value,:); % get rows of ROI table that match request
                % plot
                figure
                app.clObj.plotChannelsROI([],ROItableSlice,strcmp(app.PlotROIsindivuallyMenu.Checked,'off'))
            catch err
                app.errorBox(err)
            end
        end

        % Callback function
        function PlotchannelsallROIsButtonPushed(app, event)
            try
                if isempty(app.ROItable) % check if is not empty
                    return
                end
                % plot
                figure
                app.clObj.plotChannelsROI([],app.ROItable,strcmp(app.PlotROIsindivuallyMenu.Checked,'off'))
            catch err
                app.errorBox(err)
            end
        end

        % Callback function
        function RemovebackgroundButtonPushed(app, event)
            try
                % compute
                app.clObj = app.clObj.computeMask;
                % plot
                updateUIAxes1(app)
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: ApplymaskCheckBox
        function ApplymaskCheckBoxValueChanged(app, event)
            value = app.ApplymaskCheckBox.Value;
            try
                app.clObj.maskFlag = value;
                if app.InvertCheckBox.Value == 0 % invert
                    app.InvertCheckBox.Value = 1;
                    app.clObj = app.clObj.invertMask;
                end
                % plot
                updateUIAxes1(app)
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: Channel1EditField
        function Channel1EditFieldValueChanged(app, event)
            try
                value = app.Channel1EditField.Value;
                % check value
                numvalue = str2num(value);
                if isempty(numvalue)
                    % value is not valid
                    app.Channel1EditField.Value = 1;
                    return
                end
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: Channel2EditField
        function Channel2EditFieldValueChanged(app, event)
            try
                value = app.Channel2EditField.Value;
                % check value
                numvalue = str2num(value);
                if isempty(numvalue)
                    % value is not valid
                    app.Channel2EditField.Value = 1;
                    return
                end
            catch err
                app.errorBox(err)
            end
        end

        % Callback function
        function PlotRGBmapsliceButtonPushed(app, event)
            try
                ch1 = str2num(app.Channel1EditField.Value);
                ch2 = str2num(app.Channel2EditField.Value);
                ch3 = str2num(app.Channel3EditField.Value);
                [id,Zidx,Tidx] = getCurrentZT(app);
            
                % plot slice
                figure
                app.clObj.plotRGBcolormap2D([],id,Zidx,Tidx,ch1,ch2,ch3)
            catch err
                app.errorBox(err)
            end
        end

        % Callback function
        function PlotratiomapsliceButtonPushed(app, event)
            try
                % ask user for channels
                list = {'Channel 1','Channel 2','Channel 3'};
                [ind1,tf] = listdlg('PromptString',{'Please select at least one','channel for numerator'},'ListString',list);
                if ind1 == 0
                    error('Please select at least one channel.')
                elseif tf == 0
                    return % user pressed cancel
                end
                [ind2,tf] = listdlg('PromptString',{'Please select at least one','channel for denominator'},'ListString',list);
                if ind2 == 0
                    error('Please select at least one channel.') 
                elseif tf == 0
                    return % user pressed cancel
                end
                
                % plot
                ch = {str2num(app.Channel1EditField.Value),str2num(app.Channel2EditField.Value),str2num(app.Channel3EditField.Value)};
                normalizeFlag = app.NormCheckBox.Value;
                
                ch1 = horzcat(ch{ind1});
                ch2  = horzcat(ch{ind2});
                if isempty(ch1) || isempty(ch2)
                    error('Nominator or denominator is empty.')
                end
                
                [id,Zidx,Tidx] = getCurrentZT(app);
                
                figure
                app.clObj.plotRatioLog10_2D([],id,Zidx,Tidx,ch1,ch2,normalizeFlag)
                title(['channel(s) ' regexprep(num2str(ind1),'\s+','+') ' / channel(s) ' regexprep(num2str(ind2),'\s+','+')])
            catch err
                app.errorBox(err)
            end
        end

        % Callback function
        function CopychannelstoclipboardButtonPushed(app, event)
            try
                channelInt = cell(1,app.clObj.nInput);
                for ii = 1:app.clObj.nInput
                    channelInt{ii} = app.clObj.input.getReducedImage('mean','c');
                end
                num2clip(nancat(2,channelInt{:}));
            catch err
                app.errorBox(err)
            end
            function arraystring = num2clip(array)
                %NUM2CLIP copies a numerical-array to the clipboard
                %   
                %   ARRAYSTRING = NUM2CLIP(ARRAY)
                %   
                %   Copies the numerical array ARRAY to the clipboard as a tab-separated
                %   string.  This format is suitable for direct pasting to Excel and other
                %   programs.
                %   
                %   The tab-separated result is returned as ARRAYSTRING.  This
                %   functionality has been included for completeness.
                %   
                %Author: Grigor Browning
                %Last update: 02-Sept-2005
                %convert the numerical array to a string array
                %note that num2str pads the output array with space characters to account
                %for differing numbers of digits in each index entry
                arraystring = num2str(array); 
                arraystring(:,end+1) = char(10); %add a carrige return to the end of each row
                %reshape the array to a single line
                %note that the reshape function reshape is column based so to reshape by
                %rows one must use the inverse of the matrix
                arraystring = reshape(arraystring',1,prod(size(arraystring))); %reshape the array to a single line
                arraystringshift = [' ',arraystring]; %create a copy of arraystring shifted right by one space character
                arraystring = [arraystring,' ']; %add a space to the end of arraystring to make it the same length as arraystringshift
                %now remove the additional space charaters - keeping a single space
                %charater after each 'numerical' entry
                arraystring = arraystring((double(arraystring)~=32 | double(arraystringshift)~=32) & ~(double(arraystringshift==10) & double(arraystring)==32) );
                arraystring(double(arraystring)==32) = char(9); %convert the space characters to tab characters
                clipboard('copy',arraystring); %copy the result to the clipboard ready for pasting
            end
        end

        % Value changed function: InvertCheckBox
        function InvertCheckBoxValueChanged(app, event)
            try
                % invert
                app.clObj = app.clObj.invertMask;
                % plot
                updateUIAxes1(app)
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: VisualizationModeDropDown
        function VisualizationModeDropDownValueChanged(app, event)
            try
                % plot
                updateUIAxes1(app)
            catch err
                app.errorBox(err)
            end
        end

        % Callback function
        function CopyROIstoclipboardButtonPushed(app, event)
            try
                if isempty(app.ROItable) % check if is not empty
                    return
                end
                % get ROIs
                [Ichannel,~] = app.clObj.getChannelsROI([],app.ROItable,strcmp(app.PlotROIsindivuallyMenu.Checked,'off'));

                % prepare legend names
                labels = double(app.ROItable.label);
                
                nChannels = cellfun(@numel,Ichannel);
                if unique(nChannels) ~= 1
                    error('Cannot copy spectrum because the number of channels is not the same in all ROIs.')
                end
                channels = horzcat(Ichannel{:});
                
                % copy to clipboard
                num2clip([labels'; channels])
            catch err
                app.errorBox(err)
            end
            function arraystring = num2clip(array)
                %NUM2CLIP copies a numerical-array to the clipboard
                %   
                %   ARRAYSTRING = NUM2CLIP(ARRAY)
                %   
                %   Copies the numerical array ARRAY to the clipboard as a tab-separated
                %   string.  This format is suitable for direct pasting to Excel and other
                %   programs.
                %   
                %   The tab-separated result is returned as ARRAYSTRING.  This
                %   functionality has been included for completeness.
                %   
                %Author: Grigor Browning
                %Last update: 02-Sept-2005
                %convert the numerical array to a string array
                %note that num2str pads the output array with space characters to account
                %for differing numbers of digits in each index entry
                arraystring = num2str(array); 
                arraystring(:,end+1) = char(10); %add a carrige return to the end of each row
                %reshape the array to a single line
                %note that the reshape function reshape is column based so to reshape by
                %rows one must use the inverse of the matrix
                arraystring = reshape(arraystring',1,prod(size(arraystring))); %reshape the array to a single line
                arraystringshift = [' ',arraystring]; %create a copy of arraystring shifted right by one space character
                arraystring = [arraystring,' ']; %add a space to the end of arraystring to make it the same length as arraystringshift
                %now remove the additional space charaters - keeping a single space
                %charater after each 'numerical' entry
                arraystring = arraystring((double(arraystring)~=32 | double(arraystringshift)~=32) & ~(double(arraystringshift==10) & double(arraystring)==32) );
                arraystring(double(arraystring)==32) = char(9); %convert the space characters to tab characters
                clipboard('copy',arraystring); %copy the result to the clipboard ready for pasting
            end
        end

        % Value changed function: zSpinner
        function zSpinnerValueChanged(app, event)
            try
                % plot
                updateUIAxes1(app)
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: tSpinner
        function tSpinnerValueChanged(app, event)
            try
                % plot
                updateUIAxes1(app)
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            try
                % load data
                app.clObj = CLSMspectraLab();
%                 
%                 % check if data is volume
%                 if numel(app.clObj.input) == 1 && app.clObj.input.isVolume
%                     app.clObj.isVolume = true;
%                 elseif numel(app.clObj.input) == 1 % is no volume
%                     app.clObj.isVolume = false;
%                 else
%                     for ii = 1:numel(app.clObj.input)
%                         if app.clObj.input(ii).isVolume || app.clObj.input(ii).isTimeSeries % check if is volume or timeseries
%                             error('Please provide either one volume or multiple non-volume data sets.')
%                         end
%                     end
%                     app.clObj.isVolume = false;
%                 end
                
                % set slider(s)
                nIn = app.clObj.nInput;
                if nIn == 1
                    app.dataSpinner.Value = 1;
                    app.dataSpinner.Enable = false;
                else % set spinner limits
                    app.dataSpinner.Value = 1;
                    app.dataSpinner.Enable = true;
                    app.dataSpinner.Limits = [1 nIn];
                end
                updateZTSliders(app)
                
                % init ROI table
                app.ROItable = table('Size',[0 5],'VariableTypes',{'int32','int32','int32','int32','double'},'VariableNames',{'data','z','t','label','coords'});
                
                % set background checkbox to one, to match the loaded
                % clObj.
                app.ApplymaskCheckBox.Value = 1;
                
                % plot
                updateUIAxes1(app)
                
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: AllMenu
        function AllMenuSelected(app, event)
            try
                figure
                app.clObj.plotChannels;
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: AlltoclipboardMenu
        function AlltoclipboardMenuSelected(app, event)
            try
                channelIntVec = cell(1,app.clObj.nInput);
                for ii = 1:app.clObj.nInput
                    channelInt = getImageProcessed(app.clObj,ii,'mean','c','x','y','z','t'); % in order to have the mask applied, we cannot directly request 'c'
                    channelIntVec{ii} = mean(channelInt,2:5,'omitnan');
                end
                num2clip(nancat(2,channelIntVec{:}));
            catch err
                app.errorBox(err)
            end
            function arraystring = num2clip(array)
                %NUM2CLIP copies a numerical-array to the clipboard
                %   
                %   ARRAYSTRING = NUM2CLIP(ARRAY)
                %   
                %   Copies the numerical array ARRAY to the clipboard as a tab-separated
                %   string.  This format is suitable for direct pasting to Excel and other
                %   programs.
                %   
                %   The tab-separated result is returned as ARRAYSTRING.  This
                %   functionality has been included for completeness.
                %   
                %Author: Grigor Browning
                %Last update: 02-Sept-2005
                %convert the numerical array to a string array
                %note that num2str pads the output array with space characters to account
                %for differing numbers of digits in each index entry
                arraystring = num2str(array); 
                arraystring(:,end+1) = char(10); %add a carrige return to the end of each row
                %reshape the array to a single line
                %note that the reshape function reshape is column based so to reshape by
                %rows one must use the inverse of the matrix
                arraystring = reshape(arraystring',1,prod(size(arraystring))); %reshape the array to a single line
                arraystringshift = [' ',arraystring]; %create a copy of arraystring shifted right by one space character
                arraystring = [arraystring,' ']; %add a space to the end of arraystring to make it the same length as arraystringshift
                %now remove the additional space charaters - keeping a single space
                %charater after each 'numerical' entry
                arraystring = arraystring((double(arraystring)~=32 | double(arraystringshift)~=32) & ~(double(arraystringshift==10) & double(arraystring)==32) );
                arraystring(double(arraystring)==32) = char(9); %convert the space characters to tab characters
                clipboard('copy',arraystring); %copy the result to the clipboard ready for pasting
            end
        end

        % Menu selected function: FirstPCAMenu
        function FirstPCAMenuSelected(app, event)
            
        end

        % Menu selected function: ComputemaskMenu
        function ComputemaskMenuSelected(app, event)
            try
                hbox = msgbox('Loading image... Please wait');
                if ~isa(app.clObj.mask,'imageMask.firstpcMask')
                    app.clObj.mask = imageMask.firstpcMask; % loads mask
                    app.clObj = app.clObj.initMask; % copies to number of data loaded
                end
                
                prompt = {'Enter threshold value (empty for auto):'};
                dlgtitle = 'Input';
                dims = [1 35];
                definput = {num2str(app.clObj.mask(1).thresholds)};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                
                if isempty(answer)
                    close(hbox)
                    return
                end
                
                if isempty(answer{1})
                    threshold = [];
                else
                    threshold = str2double(answer{1});
                end
                
                if ~isempty(threshold) && isnan(threshold)
                    error('Invalid threshold, must be a number or empty.')
                end
                
                % store threshold in mask
                [app.clObj.mask.thresholds] = deal(threshold);
                
                % compute
                app.clObj = app.clObj.computeMask;
                
                close(hbox)
                
                % plot
                updateUIAxes1(app)
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: PlotROIsindivuallyMenu
        function PlotROIsindivuallyMenuSelected(app, event)
            try
                if strcmp(app.PlotROIsindivuallyMenu.Checked,'on')
                    app.PlotROIsindivuallyMenu.Checked = 'off';
                else
                    app.PlotROIsindivuallyMenu.Checked = 'on';
                end
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: ROIinzsliceMenu
        function ROIinzsliceMenuSelected(app, event)
            try
                if isempty(app.ROItable) % check if is not empty
                    return
                end
                % prune ROItable
                ROItableSlice = app.ROItable(app.ROItable.data == app.dataSpinner.Value & ...
                    app.ROItable.z == app.zSpinner.Value & ...
                    app.ROItable.t == app.tSpinner.Value,:); % get rows of ROI table that match request
                % plot
                figure
                app.clObj.plotChannelsROI([],ROItableSlice,strcmp(app.PlotROIsindivuallyMenu.Checked,'off'))
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: ROIMenu
        function ROIMenuSelected(app, event)
            try
                if isempty(app.ROItable) % check if is not empty
                    return
                end
                % plot
                figure
                app.clObj.plotChannelsROI([],app.ROItable,strcmp(app.PlotROIsindivuallyMenu.Checked,'off'))
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: ROItoclipboardMenu
        function ROItoclipboardMenuSelected(app, event)
            try
                if isempty(app.ROItable) % check if is not empty
                    return
                end
                % get ROIs
                [Ichannel,~] = app.clObj.getChannelsROI(app.ROItable,strcmp(app.PlotROIsindivuallyMenu.Checked,'off'));

                % prepare legend names
                labels = double(app.ROItable.label);
                
                nChannels = cellfun(@numel,Ichannel);
                if numel(unique(nChannels)) ~= 1
                    error('Cannot copy spectrum because the number of channels is not the same in all ROIs.')
                end
                channels = horzcat(Ichannel{:});
                
                % copy to clipboard
                num2clip([labels'; channels])
            catch err
                app.errorBox(err)
            end
            function arraystring = num2clip(array)
                %NUM2CLIP copies a numerical-array to the clipboard
                %   
                %   ARRAYSTRING = NUM2CLIP(ARRAY)
                %   
                %   Copies the numerical array ARRAY to the clipboard as a tab-separated
                %   string.  This format is suitable for direct pasting to Excel and other
                %   programs.
                %   
                %   The tab-separated result is returned as ARRAYSTRING.  This
                %   functionality has been included for completeness.
                %   
                %Author: Grigor Browning
                %Last update: 02-Sept-2005
                %convert the numerical array to a string array
                %note that num2str pads the output array with space characters to account
                %for differing numbers of digits in each index entry
                arraystring = num2str(array); 
                arraystring(:,end+1) = char(10); %add a carrige return to the end of each row
                %reshape the array to a single line
                %note that the reshape function reshape is column based so to reshape by
                %rows one must use the inverse of the matrix
                arraystring = reshape(arraystring',1,prod(size(arraystring))); %reshape the array to a single line
                arraystringshift = [' ',arraystring]; %create a copy of arraystring shifted right by one space character
                arraystring = [arraystring,' ']; %add a space to the end of arraystring to make it the same length as arraystringshift
                %now remove the additional space charaters - keeping a single space
                %charater after each 'numerical' entry
                arraystring = arraystring((double(arraystring)~=32 | double(arraystringshift)~=32) & ~(double(arraystringshift==10) & double(arraystring)==32) );
                arraystring(double(arraystring)==32) = char(9); %convert the space characters to tab characters
                clipboard('copy',arraystring); %copy the result to the clipboard ready for pasting
            end
        end

        % Callback function
        function NormalizeMenuSelected(app, event)
            try
                if strcmp(app.PlotROIsindivuallyMenu.Checked,'on')
                    app.PlotROIsindivuallyMenu.Checked = 'off';
                else
                    app.PlotROIsindivuallyMenu.Checked = 'on';
                end
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: RGBmapsliceMenu
        function RGBmapsliceMenuSelected(app, event)
            try
                ch1 = str2num(app.Channel1EditField.Value);
                ch2 = str2num(app.Channel2EditField.Value);
                ch3 = str2num(app.Channel3EditField.Value);
                [id,Zidx,Tidx] = getCurrentZT(app);
            
                % plot slice
                figure
                app.clObj.plotRGBcolormap2D([],id,Zidx,Tidx,ch1,ch2,ch3)
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: RatiomapsliceMenu
        function RatiomapsliceMenuSelected(app, event)
            try
                % ask user for channels
                list = {'Channel 1','Channel 2','Channel 3'};
                [ind1,tf] = listdlg('PromptString',{'Please select at least one','channel for numerator'},'ListString',list);
                if ind1 == 0
                    error('Please select at least one channel.')
                elseif tf == 0
                    return % user pressed cancel
                end
                [ind2,tf] = listdlg('PromptString',{'Please select at least one','channel for denominator'},'ListString',list);
                if ind2 == 0
                    error('Please select at least one channel.') 
                elseif tf == 0
                    return % user pressed cancel
                end
                
                % plot
                ch = {str2num(app.Channel1EditField.Value),str2num(app.Channel2EditField.Value),str2num(app.Channel3EditField.Value)};
                
                ch1 = horzcat(ch{ind1});
                ch2  = horzcat(ch{ind2});
                if isempty(ch1) || isempty(ch2)
                    error('Nominator or denominator is empty.')
                end
                
                [id,Zidx,Tidx] = getCurrentZT(app);
                
                figure
                app.clObj.plotRatioLog10_2D([],id,Zidx,Tidx,ch1,ch2,false)
                title(['channel(s) ' regexprep(num2str(ind1),'\s+','+') ' / channel(s) ' regexprep(num2str(ind2),'\s+','+')])
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: RatiomapslicenormalizedMenu
        function RatiomapslicenormalizedMenuSelected(app, event)
            try
                % ask user for channels
                list = {'Channel 1','Channel 2','Channel 3'};
                [ind1,tf] = listdlg('PromptString',{'Please select at least one','channel for numerator'},'ListString',list);
                if ind1 == 0
                    error('Please select at least one channel.')
                elseif tf == 0
                    return % user pressed cancel
                end
                [ind2,tf] = listdlg('PromptString',{'Please select at least one','channel for denominator'},'ListString',list);
                if ind2 == 0
                    error('Please select at least one channel.') 
                elseif tf == 0
                    return % user pressed cancel
                end
                
                % plot
                ch = {str2num(app.Channel1EditField.Value),str2num(app.Channel2EditField.Value),str2num(app.Channel3EditField.Value)};
                
                ch1 = horzcat(ch{ind1});
                ch2  = horzcat(ch{ind2});
                if isempty(ch1) || isempty(ch2)
                    error('Nominator or denominator is empty.')
                end
                
                [id,Zidx,Tidx] = getCurrentZT(app);
                
                figure
                app.clObj.plotRatioLog10_2D([],id,Zidx,Tidx,ch1,ch2,true) % difference wrt RatiomapsliceMenuSelected(app, event)
                title(['channel(s) ' regexprep(num2str(ind1),'\s+','+') ' / channel(s) ' regexprep(num2str(ind2),'\s+','+')])
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: Plothistogram1steigenimageMenu
        function Plothistogram1steigenimageMenuSelected(app, event)
        try
            data = app.dataSpinner.Value;
            figure
            app.clObj.mask(data).plotThresholdHistogram
        catch err
            app.errorBox(err)
        end
        end

        % Menu selected function: Plot1steigenimageMenu
        function Plot1steigenimageMenuSelected(app, event)
        try
            data = app.dataSpinner.Value;
            figure
            app.clObj.mask(data).plotEigenImage
        catch err
            app.errorBox(err)
        end
        end

        % Menu selected function: PlotthresholdperzsliceMenu
        function PlotthresholdperzsliceMenuSelected(app, event)
        try
            data = app.dataSpinner.Value;
            figure
            app.clObj.mask(data).plotThresholdPerZSlice
        catch err
            app.errorBox(err)
        end
        end

        % Menu selected function: ForegroundbackgroundMenu
        function ForegroundbackgroundMenuSelected(app, event)
        try
            figure
            app.clObj.plotChannelsMask()
        catch err
            app.errorBox(err)
        end
        end

        % Menu selected function: Plotcurrentmask2D3DMenu
        function Plotcurrentmask2D3DMenuSelected(app, event)
        try
            data = app.dataSpinner.Value;
            if app.clObj.input(data).isVolume
                % volume viewer
                app.clObj.mask(data).plotImage
            else
                figure
                app.clObj.mask(data).plotImage
            end
        catch err
            app.errorBox(err)
        end
        end

        % Menu selected function: ComputebackgroundMenu
        function ComputebackgroundMenuSelected(app, event)
            try
                hbox = msgbox('Loading image... Please wait');
                if ~isa(app.clObj.bgCorrection,'bgCorrection.linear')
                    app.clObj.bgCorrection = bgCorrection.linear; % loads background correction object
                    app.clObj = app.clObj.initBgCorrection; % copies to number of data loaded
                end
                
                prompt = {'Enter first channel (integer):','Enter second channel (integer):','Compute per pixel (1=true/0=false)'};
                dlgtitle = 'Input';
                dims = [1 35];
                definput = {num2str(app.clObj.bgCorrection(1).c1),num2str(app.clObj.bgCorrection(1).c2),num2str(~app.clObj.bgCorrection(1).uniformBackgroundFlag)};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                
                if isempty(answer)
                    close(hbox)
                    return
                end
                
                c1 = str2double(answer{1});
                c2 = str2double(answer{2});
                computePerPixel = str2double(answer{3});
                
                if isnan(c1)
                    error('Invalid first channel, must be an integer in the range of the channels in de data.')
                end
                
                if isnan(c2)
                    error('Invalid second channel, must be an integer in the range of the channels in de data.')
                end
                
                if ~any(computePerPixel == [1 0])
                    error('Invalid compute per pixel, must be either 0 or 1.')
                end
                
                % store threshold in bgCorrection object
                [app.clObj.bgCorrection.c1] = deal(c1);
                [app.clObj.bgCorrection.c2] = deal(c2);
                [app.clObj.bgCorrection.uniformBackgroundFlag] = deal(~computePerPixel);
                
                % compute
                app.clObj = app.clObj.computeBgCorrection;
                
                close(hbox)
                
                % plot
                updateUIAxes1(app)
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: PlotacoefficientMenu
        function PlotacoefficientMenuSelected(app, event)
        try
            data = app.dataSpinner.Value;
            if app.clObj.bgCorrection(data).isVolume
                % volume viewer
                app.clObj.bgCorrection(data).plotA
            else
                figure
                app.clObj.bgCorrection(data).plotA
            end
        catch err
            app.errorBox(err)
        end
        end

        % Menu selected function: PlotbcoefficientMenu
        function PlotbcoefficientMenuSelected(app, event)
        try
            data = app.dataSpinner.Value;
            if app.clObj.input(data).isVolume
                % volume viewer
                app.clObj.bgCorrection(data).plotB
            else
                figure
                app.clObj.bgCorrection(data).plotB
            end
        catch err
            app.errorBox(err)
        end
        end

        % Value changed function: ApplybackgroundCheckBox
        function ApplybackgroundCheckBoxValueChanged(app, event)
            value = app.ApplybackgroundCheckBox.Value;
            try
                app.clObj.bgCorrectionFlag = value;
                % plot
                updateUIAxes1(app)
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: ComputebackgroundMenu_2
        function ComputebackgroundMenu_2Selected(app, event)
            try
                hbox = msgbox('Loading image... Please wait');
                if ~isa(app.clObj.bgCorrection,'bgCorrection.reference')
                    app.clObj.bgCorrection = bgCorrection.reference; % loads background correction object
                    app.clObj = app.clObj.initBgCorrection; % copies to number of data loaded
                end
                
                % set bin factor
                [app.clObj.bgCorrection.binFactor] = deal(app.clObj.binFactor);
                
                prompt = {'Compute per pixel (1=true/0=false)'};
                dlgtitle = 'Input';
                dims = [1 35];
                definput = {num2str(~app.clObj.bgCorrection(1).uniformBackgroundFlag)};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                
                if isempty(answer)
                    close(hbox)
                    return
                end
                
                computePerPixel = str2double(answer{1});
                
                if ~any(computePerPixel == [1 0])
                    error('Invalid compute per pixel, must be either 0 or 1.')
                end
                
                % store threshold in bgCorrection object
                Iref = imageStack.import; % import background image
                [app.clObj.bgCorrection.Iref] = deal(Iref);
                [app.clObj.bgCorrection.uniformBackgroundFlag] = deal(~computePerPixel);
                
                % compute
                app.clObj = app.clObj.computeBgCorrection;
                
                close(hbox)
                
                % plot
                updateUIAxes1(app)
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: PlotbackgroundMenu
        function PlotbackgroundMenuSelected(app, event)
            try
                if ~isempty(app.clObj.bgCorrection(1).Iref)
                    channelIntVec = app.clObj.bgCorrection(1).Iref.getReducedImage('mean','c'); % in order to have the mask applied, we cannot directly request 'c'
                    figure
                    plot((1:numel(channelIntVec)),channelIntVec,'.-');
                    xlabel('Bin')
                    ylabel('Mean intensity')
                    title('Reference for background subtraction')
                end
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: startnmEditField
        function startnmEditFieldValueChanged(app, event)
            try
                update_rgb_bins(app,true)
                if ~isempty(app.clObj)
                    updateUIAxes1(app)
                end
            catch err
                    app.errorBox(err)
            end
        end

        % Value changed function: endnmEditField
        function endnmEditFieldValueChanged(app, event)
            try
                update_rgb_bins(app,true)
                if ~isempty(app.clObj)
                    updateUIAxes1(app)
                end
            catch err
                    app.errorBox(err)
            end
        end

        % Menu selected function: AddsatpixelsMenu
        function AddsatpixelsMenuSelected(app, event)
            try
                prompt = {'Threshold counts'};
                dlgtitle = 'Input';
                dims = [1 35];
                definput = {'4095'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                
                if isempty(answer)
%                     close(hbox)
                    return
                end

                if isempty(app.clObj.mask(1).I)
                    app.clObj.mask = imageMask.emptyMask; % loads mask
                    app.clObj = app.clObj.initMask; % copies to number of data loaded
                end
                
                % compute
                app.clObj = app.clObj.computeMask;
                
                app.clObj = app.clObj.maskSaturatedPixels(str2double(answer{1}));
                
                updateUIAxes1(app)
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: ResetmaskMenu
        function ResetmaskMenuSelected(app, event)
            try
                if ~isempty(app.clObj)
                    app.clObj.mask = imageMask.firstpcMask;
                    updateUIAxes1(app)
                end
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: XANESWizardMenu
        function XANESWizardMenuSelected(app, event)
            try
                wl_bins = cell(app.clObj.nInput,1);
                for ii = app.clObj.nInput
                    wl_bins{ii} = linspace(app.startnmEditField.Value,app.endnmEditField.Value,app.clObj.input(ii).getDim('c'));
                end
                app.clObj.export4XANES_Wizard([],wl_bins)
            catch err
                app.errorBox(err)
            end 
        end

        % Menu selected function: ComputenormMenu
        function ComputenormMenuSelected(app, event)
        try
            hbox = msgbox('Loading image... Please wait');
            
            if ~isa(app.clObj.normalization,'pixelNormalization.sum2one')
                app.clObj.normalization = pixelNormalization.sum2one; % loads background correction object
                app.clObj = app.clObj.initNormalization; % copies to number of data loaded
            end
            
            % compute
            app.clObj = app.clObj.computeNormalization;
            
            close(hbox)
            
            % plot
            updateUIAxes1(app)
        catch err
            app.errorBox(err)
        end 
        end

        % Value changed function: ApplynormalizationCheckBox
        function ApplynormalizationCheckBoxValueChanged(app, event)
            value = app.ApplynormalizationCheckBox.Value;
            try
                app.clObj.normalizationFlag = value;
                % plot
                updateUIAxes1(app)
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: ComputeforcurrentdataMenu
        function ComputeforcurrentdataMenuSelected(app, event)
            try
                hbox = msgbox('Loading image... Please wait');

                [id,~,~] = getCurrentZT(app);

                if ~isa(app.clObj.mask(id),'imageMask.firstpcMask')
                    app.clObj.mask(id) = imageMask.firstpcMask; % loads mask
                end
                
                prompt = {'Enter threshold value (empty for auto):'};
                dlgtitle = 'Input';
                dims = [1 35];
                definput = {num2str(app.clObj.mask(id).thresholds)};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                
                if isempty(answer)
                    close(hbox)
                    return
                end
                
                if isempty(answer{1})
                    threshold = [];
                else
                    threshold = str2double(answer{1});
                end
                
                if ~isempty(threshold) && isnan(threshold)
                    error('Invalid threshold, must be a number or empty.')
                end
                
                % store threshold in mask
                app.clObj.mask(id).thresholds = threshold;
                
                % compute
                app.clObj = app.clObj.computeMask(id);
                
                close(hbox)
                
                % plot
                updateUIAxes1(app)
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: ComputenormMenu_2
        function ComputenormMenu_2Selected(app, event)
        try
            hbox = msgbox('Loading image... Please wait');

            if ~isa(app.clObj.normalization,'pixelNormalization.singlePoint')
                app.clObj.normalization = pixelNormalization.singlePoint; % loads background correction object
                app.clObj = app.clObj.initNormalization; % copies to number of data loaded
            end

            prompt = {'Enter bins for normalization (multiple for average):'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {num2str(app.clObj.normalization(1).point)};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            if isempty(answer)
                close(hbox)
                return
            end
            
            try
                point = str2num(answer{1});
            catch
                error('Invalid points, must be an integer number or vector.')
            end
            
            
            % store threshold in mask
            [app.clObj.normalization.point] = deal(point);
            
            % compute
            app.clObj = app.clObj.computeNormalization;
            
            close(hbox)
            
            % plot
            updateUIAxes1(app)
        catch err
            app.errorBox(err)
        end 
        end

        % Menu selected function: ComputenormMenu_3
        function ComputenormMenu_3Selected(app, event)
        try
            hbox = msgbox('Loading image... Please wait');
            
            if ~isa(app.clObj.normalization,'pixelNormalization.max2one')
                app.clObj.normalization = pixelNormalization.max2one; % loads background correction object
                app.clObj = app.clObj.initNormalization; % copies to number of data loaded
            end
            
            % compute
            app.clObj = app.clObj.computeNormalization;
            
            close(hbox)
            
            % plot
            updateUIAxes1(app)
        catch err
            app.errorBox(err)
        end
        end

        % Menu selected function: BininputimageMenu
        function BininputimageMenuSelected(app, event)
        try
            prompt = {'Enter bin factor (must be an integer):'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'2'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            if isempty(answer)
                close(hbox)
                return
            end

            binFactor = str2double(answer{1});
            
            if isnan(binFactor)
                error('Please provide valid bin factor.')
            end

            app.clObj = app.clObj.getBinnedObj(binFactor);

            updateUIAxes1(app)

            disp('Binning done')
        catch err
            app.errorBox(err)
        end
        end

        % Menu selected function: SinglepixelsMenu
        function SinglepixelsMenuSelected(app, event)
        try
            figure;
            ax2 = gca;
            % Get handles for all children from ax1
            ax1Chil = app.UIAxes.Children; 
            % Copy all ax1 objects to axis 2
            copyobj(ax1Chil, ax2);
            title('Please press ''return'' when done.')
            
            [idx_x,idx_y] = ginput();

            if ~isempty(idx_x)
                
                [id,Zidx,Tidx] = getCurrentZT(app);
    
                I = app.clObj.getImageProcessed(id,'reshaped','x','y','z','t','c');
                I = squeeze(I(:,:,Zidx,Tidx,:)); % [X Y C]
                
                idx_x = floor(idx_x);
                idx_y = floor(idx_y);
                
                for ii = 1:numel(idx_x)
                    text(idx_x(ii),idx_y(ii),['Pixel ' num2str(ii)]);
                end

                figure
                for ii = 1:numel(idx_x)
                    plot(squeeze(I(idx_x(ii),idx_y(ii),:)))
                    hold on
                end
                legend()

            end

        catch err
            app.errorBox(err)
        end
        end

        % Menu selected function: ComputemaskMenu_2
        function ComputemaskMenu_2Selected(app, event)
            try
                hbox = msgbox('Loading image... Please wait');
                if ~isa(app.clObj.mask,'imageMask.emptyMask')
                    app.clObj.mask = imageMask.emptyMask; % loads mask
                    app.clObj = app.clObj.initMask; % copies to number of data loaded
                end
                
                % compute
                app.clObj = app.clObj.computeMask;
                
                close(hbox)
                
                % plot
                updateUIAxes1(app)
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: XANESWizardClustersMenu
        function XANESWizardClustersMenuSelected(app, event)
            try
                [id,~,~] = getCurrentZT(app);
                [spectrum,nPixels,clustermap] = app.clObj.getXANES_WizardSpectrum(id,[]);
                if isempty(spectrum) % user pressed cancel
                    return
                end
                
                figure
                plot(spectrum{1})
                
                names = cell(numel(nPixels{1}),1);
                for ii = 1:numel(nPixels{1})
                    names{ii} = sprintf('Cluster %i',ii);
                end
                legend(names{:})
                
                figure
                imagesc(clustermap{1})
                colormap([[1 1 1]; lines(numel(nPixels{1}))])
                colorbar
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: SaveXANESWizardspectrumMenu
        function SaveXANESWizardspectrumMenuSelected(app, event)
            try
                [id,~,~] = getCurrentZT(app);
                
                [file,path] = uigetfile('*.mat',...
                     'Select one or more XANESWizard ''KmeansResults.mat'' output files', ...
                     'MultiSelect', 'off');
                if file == 0
                    return
                end
                filenames = fullfile(path,file);

                [spectrum,nPixels] = app.clObj.getXANES_WizardSpectrum(id,filenames);
                if isempty(spectrum) % user pressed cancel
                    return
                end
                
                savename = sprintf('spectrum_clustersXANESWiz_bgc%i_msk%i_norm%i.xlsx',app.clObj.bgCorrectionFlag,app.clObj.maskFlag,app.clObj.normalizationFlag);
                xlswrite(fullfile(path,savename),[nPixels{1};spectrum{1}])
                fprintf('Written spectrum: %s\n%s\n',path,savename)
                
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: PrintpixelsinmaskMenu
        function PrintpixelsinmaskMenuSelected(app, event)
            try
                [id,~,~] = getCurrentZT(app);
                if ~isempty(app.clObj.mask(id).I)
                    fprintf('The number of pixels in the current mask (including all z,t) is %i.\n',sum(app.clObj.mask(id).I,'all'))
                end
                
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: SetfilenameforcurrentMenu
        function SetfilenameforcurrentMenuSelected(app, event)
            try
                if ~isa(app.clObj.clustering,'pixelClustering.importXANESwizard')
                    app.clObj.clustering = pixelClustering.importXANESwizard; % loads mask
                    app.clObj = app.clObj.initClustering; % copies to number of data loaded
                end
                [id,~,~] = getCurrentZT(app);
                [file,path] = uigetfile('*.mat',...
                     'Select one XANESWizard ''KmeansResults.mat'' output files', ...
                     'MultiSelect', 'off');
                if file == 0
                    error('No file loaded.')
                end
                filename = fullfile(path,file);
                app.clObj.clustering(id) = app.clObj.clustering(id).setLoadFilenames(filename);
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: ComputeMenu
        function ComputeMenuSelected(app, event)
            try
                hbox = msgbox('Loading image... Please wait');
                if ~isa(app.clObj.clustering,'pixelClustering.importXANESwizard')
                    app.clObj.clustering = pixelClustering.importXANESwizard; % loads mask
                    app.clObj = app.clObj.initClustering; % copies to number of data loaded
                end
                
                % compute
                app.clObj = app.clObj.computeClustering;
                
                close(hbox)
                
                % plot
                updateUIAxes1(app)
            catch err
                app.errorBox(err)
            end
        end

        % Callback function
        function PlotclustermapMenuSelected(app, event)
            try
                [id,~,~] = getCurrentZT(app);
                figure
                app.clObj.clustering.plotClusterMap([],id)
            catch err
                app.errorBox(err)
            end
        end

        % Callback function
        function PlotspectrumclustersMenuSelected(app, event)
            try
                [id,~,~] = getCurrentZT(app);
                figure
                app.clObj.plotChannelsClusters([],id)
            catch err
                app.errorBox(err)
            end
        end

        % Callback function
        function PlotspectrumclusterasallMenuSelected(app, event)
            try
                figure
                app.clObj.plotChannelsClusters([])
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: CopyspectrumclusterstoclipboardMenu
        function CopyspectrumclusterstoclipboardMenuSelected(app, event)
            try
                [id,~,~] = getCurrentZT(app);
                spectrum = app.clObj.getChannelsClusters(id);
                num2clip(spectrum{1});
            catch err
                app.errorBox(err)
            end
            function arraystring = num2clip(array)
                %NUM2CLIP copies a numerical-array to the clipboard
                %   
                %   ARRAYSTRING = NUM2CLIP(ARRAY)
                %   
                %   Copies the numerical array ARRAY to the clipboard as a tab-separated
                %   string.  This format is suitable for direct pasting to Excel and other
                %   programs.
                %   
                %   The tab-separated result is returned as ARRAYSTRING.  This
                %   functionality has been included for completeness.
                %   
                %Author: Grigor Browning
                %Last update: 02-Sept-2005
                %convert the numerical array to a string array
                %note that num2str pads the output array with space characters to account
                %for differing numbers of digits in each index entry
                arraystring = num2str(array); 
                arraystring(:,end+1) = char(10); %add a carrige return to the end of each row
                %reshape the array to a single line
                %note that the reshape function reshape is column based so to reshape by
                %rows one must use the inverse of the matrix
                arraystring = reshape(arraystring',1,prod(size(arraystring))); %reshape the array to a single line
                arraystringshift = [' ',arraystring]; %create a copy of arraystring shifted right by one space character
                arraystring = [arraystring,' ']; %add a space to the end of arraystring to make it the same length as arraystringshift
                %now remove the additional space charaters - keeping a single space
                %charater after each 'numerical' entry
                arraystring = arraystring((double(arraystring)~=32 | double(arraystringshift)~=32) & ~(double(arraystringshift==10) & double(arraystring)==32) );
                arraystring(double(arraystring)==32) = char(9); %convert the space characters to tab characters
                clipboard('copy',arraystring); %copy the result to the clipboard ready for pasting
            end
        end

        % Menu selected function: PlotclustermapMenu_2
        function PlotclustermapMenu_2Selected(app, event)
            try
                [id,~,~] = getCurrentZT(app);
                figure
                app.clObj.clustering.plotClusterMap([],id)
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: PlotspectrumclustersMenu_2
        function PlotspectrumclustersMenu_2Selected(app, event)
            try
                [id,~,~] = getCurrentZT(app);
                figure
                app.clObj.plotChannelsClusters([],id)
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: PlotspectrumclustersallMenu
        function PlotspectrumclustersallMenuSelected(app, event)
            try
                figure
                app.clObj.plotChannelsClusters([])
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: CopyspectrumclusterstoclipboardallMenu
        function CopyspectrumclusterstoclipboardallMenuSelected(app, event)
            try
                spectrum = app.clObj.getChannelsClusters;
                num2clip(nancat(2,spectrum{:}));
            catch err
                app.errorBox(err)
            end
            function arraystring = num2clip(array)
                %NUM2CLIP copies a numerical-array to the clipboard
                %   
                %   ARRAYSTRING = NUM2CLIP(ARRAY)
                %   
                %   Copies the numerical array ARRAY to the clipboard as a tab-separated
                %   string.  This format is suitable for direct pasting to Excel and other
                %   programs.
                %   
                %   The tab-separated result is returned as ARRAYSTRING.  This
                %   functionality has been included for completeness.
                %   
                %Author: Grigor Browning
                %Last update: 02-Sept-2005
                %convert the numerical array to a string array
                %note that num2str pads the output array with space characters to account
                %for differing numbers of digits in each index entry
                arraystring = num2str(array); 
                arraystring(:,end+1) = char(10); %add a carrige return to the end of each row
                %reshape the array to a single line
                %note that the reshape function reshape is column based so to reshape by
                %rows one must use the inverse of the matrix
                arraystring = reshape(arraystring',1,prod(size(arraystring))); %reshape the array to a single line
                arraystringshift = [' ',arraystring]; %create a copy of arraystring shifted right by one space character
                arraystring = [arraystring,' ']; %add a space to the end of arraystring to make it the same length as arraystringshift
                %now remove the additional space charaters - keeping a single space
                %charater after each 'numerical' entry
                arraystring = arraystring((double(arraystring)~=32 | double(arraystringshift)~=32) & ~(double(arraystringshift==10) & double(arraystring)==32) );
                arraystring(double(arraystring)==32) = char(9); %convert the space characters to tab characters
                clipboard('copy',arraystring); %copy the result to the clipboard ready for pasting
            end
        end

        % Menu selected function: SaveclusterstatisticsMenu
        function SaveclusterstatisticsMenuSelected(app, event)
            try
                [id,~,~] = getCurrentZT(app);
                app.clObj.getClusterStats([],id)
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: SaveclusterstatisticsallMenu
        function SaveclusterstatisticsallMenuSelected(app, event)
            try
                app.clObj.getClusterStats()
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: PlotprocessedclustersmapMenu
        function PlotprocessedclustersmapMenuSelected(app, event)
            try
                [id,~,~] = getCurrentZT(app);
                app.clObj.clustering.plotProcessedClusterMapsComparison(id);
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: ClustermapprocesssettingsMenu
        function ClustermapprocesssettingsMenuSelected(app, event)
            try
                id = 1;
                if app.clObj.clustering(id).diagonalPixConnected == true; diagonalPixConnected = 'true'; else; diagonalPixConnected = 'false'; end
                answer = inputdlg({'Minimum number of pixels in group (integer number):', 'Are clusters connected via diagonals? (true/false)'},...
                    'Please enter settings for processing the cluster map ', [1 50],{num2str(app.clObj.clustering(id).minPixGroupSize),diagonalPixConnected});
                minPixGroupSize = floor(str2double(answer{1}));
                if isnan(minPixGroupSize) || minPixGroupSize < 1
                    error('Minimum number of pixels in group must be an integer of 1 or larger.')
                end
                if strcmpi(answer{2},'true'); diagonalPixConnected = true; else; diagonalPixConnected = false; end
                [app.clObj.clustering.minPixGroupSize] = deal(minPixGroupSize);
                [app.clObj.clustering.diagonalPixConnected] = deal(diagonalPixConnected);
                app.clObj.clustering.plotProcessedClusterMapsComparison(id);
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: ComputeMenu_2
        function ComputeMenu_2Selected(app, event)
            try
                id = 1;
                answer = inputdlg({'Threshold value background (value between 0 and 1):', 'Index of reference spectra that is in background (list of integers, e.g. "[1 4]")'},...
                    'Please enter settings for computation of the similarity fit', [1 50],{num2str(app.clObj.clustering(id).thresholdBg),mat2str(app.clObj.clustering(id).belowThresholdIdx)});
                thresholdBg = str2double(answer{1});
                if isnan(thresholdBg) || thresholdBg < 0 || thresholdBg > 1
                    error('Threshold value background must have a value between 0 and 1.')
                end
                belowThresholdIdx = floor(str2num(answer{2}));
                if any(isnan(belowThresholdIdx)) || any(belowThresholdIdx < 1)
                    error('Invalid spectrum index.')
                end
                [app.clObj.clustering.thresholdBg] = deal(thresholdBg);
                [app.clObj.clustering.belowThresholdIdx] = deal(belowThresholdIdx);

                hbox = msgbox('Computing... Please wait');
                if ~isa(app.clObj.clustering,'pixelClustering.similarityFit')
                    app.clObj.clustering = pixelClustering.similarityFit; % loads mask
                    app.clObj = app.clObj.initClustering; % copies to number of data loaded
                end
                
                % compute
                app.clObj = app.clObj.computeClustering;
                
                close(hbox)
                
                % plot
                updateUIAxes1(app)
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: LoadreferencespectraMenu
        function LoadreferencespectraMenuSelected(app, event)
            try
                if ~isa(app.clObj.clustering,'pixelClustering.similarityFit')
                    app.clObj.clustering = pixelClustering.similarityFit; % loads mask
                    app.clObj = app.clObj.initClustering; % copies to number of data loaded
                end
                [id,~,~] = getCurrentZT(app); % .txt, .dat, or .csv .xls, .xlsb, .xlsm, .xlsx, .xltm, .xltx, or .ods
                [file,path] = uigetfile(...
                     {'*.csv;*.dat;*.txt',...
                     'Delimited text files (*.csv;*.dat;*.txt)';...
                     '*.xls,*.xlsb,*.xlsm,*.xlsx,*.xltm,*.xltx,*.ods',...
                     'Spreadsheet files (*.xls,*.xlsb,*.xlsm,*.xlsx,*.xltm,*.xltx,*.ods)'},...
                     'Select one file with reference spectra', ...
                     'MultiSelect', 'off');
                if file == 0
                    error('No file loaded.')
                end
                filename = fullfile(path,file);
                app.clObj.clustering(id) = app.clObj.clustering(id).setLoadFilenames(filename);
            catch err
                app.errorBox(err)
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.CLSMspectraLab.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {529, 529};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CLSMspectraLab = uifigure('Visible', 'off');
            app.CLSMspectraLab.Name = 'CLSM Spectra Lab';
            app.CLSMspectraLab.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.CLSMspectraLab);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export';
            app.XANESWizardMenu = uimenu(app.ExportMenu);
            app.XANESWizardMenu.MenuSelectedFcn = createCallbackFcn(app, @XANESWizardMenuSelected, true);
            app.XANESWizardMenu.Text = 'XANES Wizard';
            app.DebugMenu = uimenu(app.FileMenu);
            app.DebugMenu.Separator = 'on';
            app.DebugMenu.Text = 'Debug';
            app.BackgroundMenu = uimenu(app.CLSMspectraLab);
            app.BackgroundMenu.Text = 'Background';
            app.LinearMenu = uimenu(app.BackgroundMenu);
            app.LinearMenu.Text = 'Linear';
            app.ComputebackgroundMenu = uimenu(app.LinearMenu);
            app.ComputebackgroundMenu.MenuSelectedFcn = createCallbackFcn(app, @ComputebackgroundMenuSelected, true);
            app.ComputebackgroundMenu.Text = 'Compute background';
            app.PlotacoefficientMenu = uimenu(app.LinearMenu);
            app.PlotacoefficientMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotacoefficientMenuSelected, true);
            app.PlotacoefficientMenu.Text = 'Plot a coefficient';
            app.PlotbcoefficientMenu = uimenu(app.LinearMenu);
            app.PlotbcoefficientMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotbcoefficientMenuSelected, true);
            app.PlotbcoefficientMenu.Text = 'Plot b coefficient';
            app.ReferencespectrumMenu = uimenu(app.BackgroundMenu);
            app.ReferencespectrumMenu.Text = 'Reference spectrum';
            app.ComputebackgroundMenu_2 = uimenu(app.ReferencespectrumMenu);
            app.ComputebackgroundMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ComputebackgroundMenu_2Selected, true);
            app.ComputebackgroundMenu_2.Text = 'Compute background';
            app.PlotbackgroundMenu = uimenu(app.ReferencespectrumMenu);
            app.PlotbackgroundMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotbackgroundMenuSelected, true);
            app.PlotbackgroundMenu.Text = 'Plot background';
            app.MaskMenu = uimenu(app.CLSMspectraLab);
            app.MaskMenu.Text = 'Mask';
            app.FirstPCAMenu = uimenu(app.MaskMenu);
            app.FirstPCAMenu.MenuSelectedFcn = createCallbackFcn(app, @FirstPCAMenuSelected, true);
            app.FirstPCAMenu.Text = 'First PCA';
            app.ComputemaskMenu = uimenu(app.FirstPCAMenu);
            app.ComputemaskMenu.MenuSelectedFcn = createCallbackFcn(app, @ComputemaskMenuSelected, true);
            app.ComputemaskMenu.Text = 'Compute mask';
            app.ComputeforcurrentdataMenu = uimenu(app.FirstPCAMenu);
            app.ComputeforcurrentdataMenu.MenuSelectedFcn = createCallbackFcn(app, @ComputeforcurrentdataMenuSelected, true);
            app.ComputeforcurrentdataMenu.Text = 'Compute for current data';
            app.Plothistogram1steigenimageMenu = uimenu(app.FirstPCAMenu);
            app.Plothistogram1steigenimageMenu.MenuSelectedFcn = createCallbackFcn(app, @Plothistogram1steigenimageMenuSelected, true);
            app.Plothistogram1steigenimageMenu.Text = 'Plot histogram 1st eigen image';
            app.Plot1steigenimageMenu = uimenu(app.FirstPCAMenu);
            app.Plot1steigenimageMenu.MenuSelectedFcn = createCallbackFcn(app, @Plot1steigenimageMenuSelected, true);
            app.Plot1steigenimageMenu.Text = 'Plot 1st eigen image';
            app.PlotthresholdperzsliceMenu = uimenu(app.FirstPCAMenu);
            app.PlotthresholdperzsliceMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotthresholdperzsliceMenuSelected, true);
            app.PlotthresholdperzsliceMenu.Text = 'Plot threshold per z-slice';
            app.EmptyMenu = uimenu(app.MaskMenu);
            app.EmptyMenu.Text = 'Empty';
            app.ComputemaskMenu_2 = uimenu(app.EmptyMenu);
            app.ComputemaskMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ComputemaskMenu_2Selected, true);
            app.ComputemaskMenu_2.Text = 'Compute mask';
            app.AddsatpixelsMenu = uimenu(app.MaskMenu);
            app.AddsatpixelsMenu.MenuSelectedFcn = createCallbackFcn(app, @AddsatpixelsMenuSelected, true);
            app.AddsatpixelsMenu.Separator = 'on';
            app.AddsatpixelsMenu.Text = 'Add sat. pixels';
            app.PrintpixelsinmaskMenu = uimenu(app.MaskMenu);
            app.PrintpixelsinmaskMenu.MenuSelectedFcn = createCallbackFcn(app, @PrintpixelsinmaskMenuSelected, true);
            app.PrintpixelsinmaskMenu.Text = 'Print #pixels in mask';
            app.ResetmaskMenu = uimenu(app.MaskMenu);
            app.ResetmaskMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetmaskMenuSelected, true);
            app.ResetmaskMenu.Text = 'Reset mask';
            app.NormalizationMenu = uimenu(app.CLSMspectraLab);
            app.NormalizationMenu.Text = 'Normalization';
            app.MaxMenu = uimenu(app.NormalizationMenu);
            app.MaxMenu.Text = 'Max';
            app.ComputenormMenu_3 = uimenu(app.MaxMenu);
            app.ComputenormMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ComputenormMenu_3Selected, true);
            app.ComputenormMenu_3.Text = 'Compute norm.';
            app.SummedMenu = uimenu(app.NormalizationMenu);
            app.SummedMenu.Text = 'Summed';
            app.ComputenormMenu = uimenu(app.SummedMenu);
            app.ComputenormMenu.MenuSelectedFcn = createCallbackFcn(app, @ComputenormMenuSelected, true);
            app.ComputenormMenu.Text = 'Compute norm.';
            app.SinglepointMenu = uimenu(app.NormalizationMenu);
            app.SinglepointMenu.Text = 'Single point';
            app.ComputenormMenu_2 = uimenu(app.SinglepointMenu);
            app.ComputenormMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ComputenormMenu_2Selected, true);
            app.ComputenormMenu_2.Text = 'Compute norm.';
            app.ClusteringMenu = uimenu(app.CLSMspectraLab);
            app.ClusteringMenu.Text = 'Clustering';
            app.ImportXANESWizardMenu = uimenu(app.ClusteringMenu);
            app.ImportXANESWizardMenu.Text = 'Import XANES Wizard';
            app.SetfilenameforcurrentMenu = uimenu(app.ImportXANESWizardMenu);
            app.SetfilenameforcurrentMenu.MenuSelectedFcn = createCallbackFcn(app, @SetfilenameforcurrentMenuSelected, true);
            app.SetfilenameforcurrentMenu.Text = 'Set filename for current';
            app.ComputeMenu = uimenu(app.ImportXANESWizardMenu);
            app.ComputeMenu.MenuSelectedFcn = createCallbackFcn(app, @ComputeMenuSelected, true);
            app.ComputeMenu.Text = 'Compute';
            app.SimilarityfitMenu = uimenu(app.ClusteringMenu);
            app.SimilarityfitMenu.Text = 'Similarity fit';
            app.LoadreferencespectraMenu = uimenu(app.SimilarityfitMenu);
            app.LoadreferencespectraMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadreferencespectraMenuSelected, true);
            app.LoadreferencespectraMenu.Text = 'Load reference spectra';
            app.ComputeMenu_2 = uimenu(app.SimilarityfitMenu);
            app.ComputeMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ComputeMenu_2Selected, true);
            app.ComputeMenu_2.Text = 'Compute';
            app.PlotclustermapMenu_2 = uimenu(app.ClusteringMenu);
            app.PlotclustermapMenu_2.MenuSelectedFcn = createCallbackFcn(app, @PlotclustermapMenu_2Selected, true);
            app.PlotclustermapMenu_2.Separator = 'on';
            app.PlotclustermapMenu_2.Text = 'Plot cluster map';
            app.PlotspectrumclustersMenu_2 = uimenu(app.ClusteringMenu);
            app.PlotspectrumclustersMenu_2.MenuSelectedFcn = createCallbackFcn(app, @PlotspectrumclustersMenu_2Selected, true);
            app.PlotspectrumclustersMenu_2.Text = 'Plot spectrum clusters';
            app.PlotspectrumclustersallMenu = uimenu(app.ClusteringMenu);
            app.PlotspectrumclustersallMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotspectrumclustersallMenuSelected, true);
            app.PlotspectrumclustersallMenu.Text = 'Plot spectrum clusters all';
            app.CopyspectrumclusterstoclipboardMenu = uimenu(app.ClusteringMenu);
            app.CopyspectrumclusterstoclipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyspectrumclusterstoclipboardMenuSelected, true);
            app.CopyspectrumclusterstoclipboardMenu.Text = 'Copy spectrum clusters to clipboard';
            app.CopyspectrumclusterstoclipboardallMenu = uimenu(app.ClusteringMenu);
            app.CopyspectrumclusterstoclipboardallMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyspectrumclusterstoclipboardallMenuSelected, true);
            app.CopyspectrumclusterstoclipboardallMenu.Text = 'Copy spectrum clusters to clipboard all';
            app.ClustermapprocesssettingsMenu = uimenu(app.ClusteringMenu);
            app.ClustermapprocesssettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @ClustermapprocesssettingsMenuSelected, true);
            app.ClustermapprocesssettingsMenu.Separator = 'on';
            app.ClustermapprocesssettingsMenu.Text = 'Cluster map process settings';
            app.PlotprocessedclustersmapMenu = uimenu(app.ClusteringMenu);
            app.PlotprocessedclustersmapMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotprocessedclustersmapMenuSelected, true);
            app.PlotprocessedclustersmapMenu.Text = 'Plot processed clusters map';
            app.SaveclusterstatisticsMenu = uimenu(app.ClusteringMenu);
            app.SaveclusterstatisticsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveclusterstatisticsMenuSelected, true);
            app.SaveclusterstatisticsMenu.Text = 'Save cluster statistics';
            app.SaveclusterstatisticsallMenu = uimenu(app.ClusteringMenu);
            app.SaveclusterstatisticsallMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveclusterstatisticsallMenuSelected, true);
            app.SaveclusterstatisticsallMenu.Text = 'Save cluster statistics all';
            app.ToolsMenu = uimenu(app.CLSMspectraLab);
            app.ToolsMenu.Text = 'Tools';
            app.BininputimageMenu = uimenu(app.ToolsMenu);
            app.BininputimageMenu.MenuSelectedFcn = createCallbackFcn(app, @BininputimageMenuSelected, true);
            app.BininputimageMenu.Text = 'Bin input image';
            app.SaveXANESWizardspectrumMenu = uimenu(app.ToolsMenu);
            app.SaveXANESWizardspectrumMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveXANESWizardspectrumMenuSelected, true);
            app.SaveXANESWizardspectrumMenu.Text = 'Save XANES Wizard spectrum';
            app.PlotMenu = uimenu(app.CLSMspectraLab);
            app.PlotMenu.Text = 'Plot';
            app.PlotspectrumMenu = uimenu(app.PlotMenu);
            app.PlotspectrumMenu.Text = 'Plot spectrum';
            app.PlotROIsindivuallyMenu = uimenu(app.PlotspectrumMenu);
            app.PlotROIsindivuallyMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotROIsindivuallyMenuSelected, true);
            app.PlotROIsindivuallyMenu.Checked = 'on';
            app.PlotROIsindivuallyMenu.Text = 'Plot ROIs indivually';
            app.AllMenu = uimenu(app.PlotspectrumMenu);
            app.AllMenu.MenuSelectedFcn = createCallbackFcn(app, @AllMenuSelected, true);
            app.AllMenu.Separator = 'on';
            app.AllMenu.Text = 'All';
            app.ROIinzsliceMenu = uimenu(app.PlotspectrumMenu);
            app.ROIinzsliceMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIinzsliceMenuSelected, true);
            app.ROIinzsliceMenu.Text = 'ROI in z-slice';
            app.ROIMenu = uimenu(app.PlotspectrumMenu);
            app.ROIMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIMenuSelected, true);
            app.ROIMenu.Text = 'ROI';
            app.SinglepixelsMenu = uimenu(app.PlotspectrumMenu);
            app.SinglepixelsMenu.MenuSelectedFcn = createCallbackFcn(app, @SinglepixelsMenuSelected, true);
            app.SinglepixelsMenu.Text = 'Single pixels';
            app.AlltoclipboardMenu = uimenu(app.PlotspectrumMenu);
            app.AlltoclipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @AlltoclipboardMenuSelected, true);
            app.AlltoclipboardMenu.Text = 'All to clipboard';
            app.ROItoclipboardMenu = uimenu(app.PlotspectrumMenu);
            app.ROItoclipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @ROItoclipboardMenuSelected, true);
            app.ROItoclipboardMenu.Text = 'ROI to clipboard';
            app.ForegroundbackgroundMenu = uimenu(app.PlotspectrumMenu);
            app.ForegroundbackgroundMenu.MenuSelectedFcn = createCallbackFcn(app, @ForegroundbackgroundMenuSelected, true);
            app.ForegroundbackgroundMenu.Text = 'Foreground/background';
            app.XANESWizardClustersMenu = uimenu(app.PlotspectrumMenu);
            app.XANESWizardClustersMenu.MenuSelectedFcn = createCallbackFcn(app, @XANESWizardClustersMenuSelected, true);
            app.XANESWizardClustersMenu.Text = 'XANES Wizard Clusters';
            app.PlotchannelsMenu = uimenu(app.PlotMenu);
            app.PlotchannelsMenu.Text = 'Plot channels';
            app.RGBmapsliceMenu = uimenu(app.PlotchannelsMenu);
            app.RGBmapsliceMenu.MenuSelectedFcn = createCallbackFcn(app, @RGBmapsliceMenuSelected, true);
            app.RGBmapsliceMenu.Text = 'RGB map slice';
            app.RatiomapsliceMenu = uimenu(app.PlotchannelsMenu);
            app.RatiomapsliceMenu.MenuSelectedFcn = createCallbackFcn(app, @RatiomapsliceMenuSelected, true);
            app.RatiomapsliceMenu.Text = 'Ratio map slice';
            app.RatiomapslicenormalizedMenu = uimenu(app.PlotchannelsMenu);
            app.RatiomapslicenormalizedMenu.MenuSelectedFcn = createCallbackFcn(app, @RatiomapslicenormalizedMenuSelected, true);
            app.RatiomapslicenormalizedMenu.Text = 'Ratio map slice (normalized)';
            app.Plotcurrentmask2D3DMenu = uimenu(app.PlotMenu);
            app.Plotcurrentmask2D3DMenu.MenuSelectedFcn = createCallbackFcn(app, @Plotcurrentmask2D3DMenuSelected, true);
            app.Plotcurrentmask2D3DMenu.Text = 'Plot current mask 2D/3D';
            app.GridLayout = uigridlayout(app.CLSMspectraLab);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.LeftPanel);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.DrawROIButton = uibutton(app.LeftPanel, 'push');
            app.DrawROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawROIButtonPushed, true);
            app.DrawROIButton.Text = 'Draw ROI';
            app.ListBox = uilistbox(app.LeftPanel);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.DeleteselectedROIButton = uibutton(app.LeftPanel, 'push');
            app.DeleteselectedROIButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedROIButtonPushed, true);
            app.DeleteselectedROIButton.Text = 'Delete selected ROI';
            app.ApplymaskCheckBox = uicheckbox(app.LeftPanel);
            app.ApplymaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplymaskCheckBoxValueChanged, true);
            app.ApplymaskCheckBox.Text = 'Apply mask';
            app.ApplymaskCheckBox.Value = true;
            app.Channel1EditFieldLabel = uilabel(app.LeftPanel);
            app.Channel1EditFieldLabel.Text = 'Channel 1';
            app.Channel1EditField = uieditfield(app.LeftPanel, 'text');
            app.Channel1EditField.ValueChangedFcn = createCallbackFcn(app, @Channel1EditFieldValueChanged, true);
            app.Channel2EditFieldLabel = uilabel(app.LeftPanel);
            app.Channel2EditFieldLabel.Text = 'Channel 2';
            app.Channel2EditField = uieditfield(app.LeftPanel, 'text');
            app.Channel2EditField.ValueChangedFcn = createCallbackFcn(app, @Channel2EditFieldValueChanged, true);
            app.InvertCheckBox = uicheckbox(app.LeftPanel);
            app.InvertCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertCheckBoxValueChanged, true);
            app.InvertCheckBox.Text = 'Invert';
            app.Channel3EditFieldLabel = uilabel(app.LeftPanel);
            app.Channel3EditFieldLabel.Text = 'Channel 3';
            app.Channel3EditField = uieditfield(app.LeftPanel, 'text');
            app.ApplybackgroundCheckBox = uicheckbox(app.LeftPanel);
            app.ApplybackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplybackgroundCheckBoxValueChanged, true);
            app.ApplybackgroundCheckBox.Text = 'Apply background';
            app.ApplybackgroundCheckBox.Value = true;
            app.startnmEditFieldLabel = uilabel(app.LeftPanel);
            app.startnmEditFieldLabel.Text = 'λ_start (nm)';
            app.startnmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.startnmEditField.LowerLimitInclusive = 'off';
            app.startnmEditField.UpperLimitInclusive = 'off';
            app.startnmEditField.Limits = [0 Inf];
            app.startnmEditField.ValueChangedFcn = createCallbackFcn(app, @startnmEditFieldValueChanged, true);
            app.startnmEditField.Value = 420;
            app.endnmEditFieldLabel = uilabel(app.LeftPanel);
            app.endnmEditFieldLabel.Text = 'λ_end (nm)';
            app.endnmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.endnmEditField.LowerLimitInclusive = 'off';
            app.endnmEditField.UpperLimitInclusive = 'off';
            app.endnmEditField.Limits = [0 Inf];
            app.endnmEditField.ValueChangedFcn = createCallbackFcn(app, @endnmEditFieldValueChanged, true);
            app.endnmEditField.Value = 700;
            app.ApplynormalizationCheckBox = uicheckbox(app.LeftPanel);
            app.ApplynormalizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplynormalizationCheckBoxValueChanged, true);
            app.ApplynormalizationCheckBox.Text = 'Apply normalization';
            app.ApplynormalizationCheckBox.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.dataSpinnerLabel = uilabel(app.RightPanel);
            app.dataSpinnerLabel.Text = 'data';
            app.dataSpinner = uispinner(app.RightPanel);
            app.dataSpinner.Limits = [1 Inf];
            app.dataSpinner.RoundFractionalValues = 'on';
            app.dataSpinner.ValueChangedFcn = createCallbackFcn(app, @dataSpinnerValueChanged, true);
            app.dataSpinner.Editable = 'off';
            app.dataSpinner.Value = 1;
            app.VisualizationModeDropDownLabel = uilabel(app.RightPanel);
            app.VisualizationModeDropDownLabel.Text = 'Visualization Mode';
            app.VisualizationModeDropDown = uidropdown(app.RightPanel);
            app.VisualizationModeDropDown.Items = {'Summed intensity', 'True colour', 'RGB blocks'};
            app.VisualizationModeDropDown.ValueChangedFcn = createCallbackFcn(app, @VisualizationModeDropDownValueChanged, true);
            app.VisualizationModeDropDown.Value = 'Summed intensity';
            app.zSpinnerLabel = uilabel(app.RightPanel);
            app.zSpinnerLabel.Text = 'z';
            app.zSpinner = uispinner(app.RightPanel);
            app.zSpinner.Limits = [1 Inf];
            app.zSpinner.RoundFractionalValues = 'on';
            app.zSpinner.ValueChangedFcn = createCallbackFcn(app, @zSpinnerValueChanged, true);
            app.zSpinner.Editable = 'off';
            app.zSpinner.Value = 1;
            app.tSpinnerLabel = uilabel(app.RightPanel);
            app.tSpinnerLabel.Text = 't';
            app.tSpinner = uispinner(app.RightPanel);
            app.tSpinner.Limits = [1 Inf];
            app.tSpinner.RoundFractionalValues = 'on';
            app.tSpinner.ValueChangedFcn = createCallbackFcn(app, @tSpinnerValueChanged, true);
            app.tSpinner.Editable = 'off';
            app.tSpinner.Value = 1;
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.CLSMspectraLab.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CLSMspectraLabGUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CLSMspectraLab)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.CLSMspectraLab)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CLSMspectraLab)
        end
    end
end